classdef Finance_Tracker_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinanceTracker           matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SetDataDirectoryMenu     matlab.ui.container.Menu
        ImportExpensescsvMenu    matlab.ui.container.Menu
        ClonePreviousMonthMenu   matlab.ui.container.Menu
        LowerTabs                matlab.ui.container.TabGroup
        LowerBalancesTab         matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
        FinnCUBox                matlab.ui.control.NumericEditField
        FinnCULabel              matlab.ui.control.Label
        AssetsBox                matlab.ui.control.NumericEditField
        AssetsLabel              matlab.ui.control.Label
        NSLSCBox                 matlab.ui.control.NumericEditField
        NSLSCEditFieldLabel      matlab.ui.control.Label
        PayPalBox                matlab.ui.control.NumericEditField
        PayPalEditFieldLabel     matlab.ui.control.Label
        RobinhoodBox             matlab.ui.control.NumericEditField
        RobinhoodEditFieldLabel  matlab.ui.control.Label
        WellsFargoBox            matlab.ui.control.NumericEditField
        WellsFargoLabel          matlab.ui.control.Label
        LowerIncomeExpenseTab    matlab.ui.container.Tab
        ExpenseTable             matlab.ui.control.Table
        IncomeOther              matlab.ui.control.NumericEditField
        IncomeOtherLabel         matlab.ui.control.Label
        IncomeJob                matlab.ui.control.NumericEditField
        IncomeJobLabel           matlab.ui.control.Label
        MonthPanel               matlab.ui.container.Panel
        LoadMonthButton          matlab.ui.control.Button
        MonthDropDown            matlab.ui.control.DropDown
        MonthDropDownLabel       matlab.ui.control.Label
        SaveMonthButton          matlab.ui.control.Button
        NotesTextArea            matlab.ui.control.TextArea
        NotesTextAreaLabel       matlab.ui.control.Label
        MonthSpinner             matlab.ui.control.Spinner
        YearDropDown             matlab.ui.control.DropDown
        YearDropDownLabel        matlab.ui.control.Label
        UpperTabs                matlab.ui.container.TabGroup
        UpperBalancesTab         matlab.ui.container.Tab
        Balance_Axes             matlab.ui.control.UIAxes
        UpperIncomeExpenseTab    matlab.ui.container.Tab
        ExpPie_Axes              matlab.ui.control.UIAxes
        IncPie_Axes              matlab.ui.control.UIAxes
        IncExp_Axes              matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        AddItemMenu              matlab.ui.container.Menu
        DeleteItemMenu           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Year % Description
        Month % Description
        Data
        YearName
        DataDirectory %Directory for holding all user-specific data
    end
    
    methods (Access = private)
        %----------------------------------------------------------------------------------------%
        %----------------------------------------------------------------------------------------%        
        function ExpenseTableRefresh(app)
            try
                app.ExpenseTable.Data = table2cell(struct2table(app.Data.(app.YearName)(app.Month).Expenses_Lists));
            catch
                app.ExpenseTable.Data = [];
            end
        end
        %----------------------------------------------------------------------------------------%
        %----------------------------------------------------------------------------------------%    
        function ExpensesListSave(app)
            ExpenseData = app.ExpenseTable.Data;
      
            app.Data.(app.YearName)(app.Month).Expenses.Bills = 0; %Rent plus any other bills
            app.Data.(app.YearName)(app.Month).Expenses.Food = 0;
            app.Data.(app.YearName)(app.Month).Expenses.Car = 0;      
            app.Data.(app.YearName)(app.Month).Expenses.Transfers = 0;    
            app.Data.(app.YearName)(app.Month).Expenses.WorkTravel = 0;     
            app.Data.(app.YearName)(app.Month).Expenses.Other = 0;  
    
            for iLines = 1:length(ExpenseData)
                switch ExpenseData{iLines,1}
                    case 'Bills'
                        app.Data.(app.YearName)(app.Month).Expenses.Bills = app.Data.(app.YearName)(app.Month).Expenses.Bills + ExpenseData{iLines,2};
                        
                    case 'Food'
                        app.Data.(app.YearName)(app.Month).Expenses.Food = app.Data.(app.YearName)(app.Month).Expenses.Food + ExpenseData{iLines,2};                        
                        
                    case 'Car'
                        app.Data.(app.YearName)(app.Month).Expenses.Car = app.Data.(app.YearName)(app.Month).Expenses.Car + ExpenseData{iLines,2};                        
                        
                    case 'Transfers'
                        app.Data.(app.YearName)(app.Month).Expenses.Transfers = app.Data.(app.YearName)(app.Month).Expenses.Transfers + ExpenseData{iLines,2};                        
                        
                    case 'WorkTravel'
                        app.Data.(app.YearName)(app.Month).Expenses.WorkTravel = app.Data.(app.YearName)(app.Month).Expenses.WorkTravel + ExpenseData{iLines,2};                        
                        
                    case 'Other'
                        app.Data.(app.YearName)(app.Month).Expenses.Other = app.Data.(app.YearName)(app.Month).Expenses.Other + ExpenseData{iLines,2};                   
                    otherwise            
                end
            end
        end
        %----------------------------------------------------------------------------------------%
        %----------------------------------------------------------------------------------------%          
        function LoadMonth(app)
            %Updates the interface components according to data for the
            %current month

            %Load Balances
            app.WellsFargoBox.Value = app.Data.(app.YearName)(app.Month).Balances.WellsFargo;              
            app.RobinhoodBox.Value = app.Data.(app.YearName)(app.Month).Balances.RobinHood; 
            app.AssetsBox.Value = app.Data.(app.YearName)(app.Month).Balances.Assets;            
            app.FinnCUBox.Value = app.Data.(app.YearName)(app.Month).Balances.FinnCU;              
            app.PayPalBox.Value = app.Data.(app.YearName)(app.Month).Balances.PayPal;                        
            app.NSLSCBox.Value = app.Data.(app.YearName)(app.Month).Balances.NSLSC;     
            
            app.NotesTextArea.Value = app.Data.(app.YearName)(app.Month).Notes;  
            
            %Load Incomes
            app.IncomeJob.Value = app.Data.(app.YearName)(app.Month).Incomes.Job;           
            app.IncomeOther.Value = app.Data.(app.YearName)(app.Month).Incomes.Other;
            
            %Load Expenses
            ExpenseTableRefresh(app)
                        
        end
        %----------------------------------------------------------------------------------------%
        %----------------------------------------------------------------------------------------%          
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %----------------------------------------------------------------------------------------%
            % SET AXES PROPERTIES
            app.Balance_Axes.Title.Color = 'white';
            app.IncExp_Axes.Title.Color = 'white';
            app.IncPie_Axes.Title.Color = 'white';
            app.ExpPie_Axes.Title.Color = 'white';

            app.DataDirectory = 'C:\';
            
            app.MonthDropDown.ItemsData = 1:1:12;
            app.MonthDropDown.Value = 1;            
            app.Year = 2020;
            app.Month = 1;
            app.YearName = 'Y2020';
            
            app.Data = load('D:\!Orion_Documents\Financial\!OM_Finance_Tracker\OM_Finance_Data.mat'); % Load in saved app.Data structure
            app.Data = app.Data.Data;
            
            ExpTypes = {'-','Bills','Food','Car','Transfer','WorkTravel','Other'};
            
            app.ExpenseTable.BackgroundColor = [0.12 0.12 0.12; 0.00 0.00 0.00];
            app.ExpenseTable.ColumnEditable = [true false false];            
            app.ExpenseTable.ColumnFormat = {ExpTypes, 'numeric', 'char'};
            app.ExpenseTable.ColumnWidth = {'auto','auto',800};

        end

        % Value changed function: MonthSpinner
        function MonthSpinnerValueChanged(app, event)
            app.MonthDropDown.Value = -app.MonthSpinner.Value;  
            app.Month = -app.MonthSpinner.Value;    

        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            app.MonthSpinner.Value = -app.MonthDropDown.Value; 
            app.Month = app.MonthDropDown.Value;    

        end

        % Button pushed function: SaveMonthButton
        function SaveMonthButtonPushed(app, event)

            %Save balances vals pulled from components
            app.Data.(app.YearName)(app.Month).Balances.WellsFargo = app.WellsFargoBox.Value;              
            app.Data.(app.YearName)(app.Month).Balances.RobinHood = app.RobinhoodBox.Value; 
            app.Data.(app.YearName)(app.Month).Balances.Assets = app.AssetsBox.Value;            
            app.Data.(app.YearName)(app.Month).Balances.FinnCU = app.FinnCUBox.Value;              
            app.Data.(app.YearName)(app.Month).Balances.PayPal = app.PayPalBox.Value;                        
            app.Data.(app.YearName)(app.Month).Balances.NSLSC = app.NSLSCBox.Value;     
            
            app.Data.(app.YearName)(app.Month).Notes = app.NotesTextArea.Value;  
            
            %Save Incomes
            app.Data.(app.YearName)(app.Month).Incomes.Job = app.IncomeJob.Value;           
            app.Data.(app.YearName)(app.Month).Incomes.Other = app.IncomeOther.Value;
            
            %Save Expenses
            ExpensesListSave(app)
            
            %Find year and month of previous month for delta calcs
            if app.Month == 1 
                PrevYear = strcat('Y',num2str(app.Year-1)); 
                PrevMonth = 12;
            else
                PrevYear = app.Year;
                PrevMonth = app.Month - 1;
            end
            
            %Find Balances Deltas
            try
                app.Data.(app.YearName)(app.Month).Balance_Deltas.WellsFargo = app.Data.(app.YearName)(app.Month).Balances.WellsFargo - app.Data.(PrevYear)(PrevMonth).Balances.WellsFargo;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.RobinHood  = app.Data.(app.YearName)(app.Month).Balances.RobinHood - app.Data.(PrevYear)(PrevMonth).Balances.RobinHood;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.Assets     = app.Data.(app.YearName)(app.Month).Balances.Assets - app.Data.(PrevYear)(PrevMonth).Balances.Assets;                
                app.Data.(app.YearName)(app.Month).Balance_Deltas.FinnCU     = app.Data.(app.YearName)(app.Month).Balances.FinnCU - app.Data.(PrevYear)(PrevMonth).Balances.FinnCU;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.PayPal     = app.Data.(app.YearName)(app.Month).Balances.PayPal - app.Data.(PrevYear)(PrevMonth).Balances.PayPal;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.NSLSC      = app.Data.(app.YearName)(app.Month).Balances.NSLSC  - app.Data.(PrevYear)(PrevMonth).Balances.NSLSC;
            catch
                app.Data.(app.YearName)(app.Month).Balance_Deltas.WellsFargo = 0;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.RobinHood = 0;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.Assets = 0;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.FinnCU = 0;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.PayPal = 0;
                app.Data.(app.YearName)(app.Month).Balance_Deltas.NSLSC = 0;                
            end
            
            %Save updated data to file
            Data = app.Data;
            cd('D:\!Orion_Programs\!Source_Controlled\Finances')
            save('OM_Finance_Data','Data')
            uialert(app.FinanceTracker,'Month Saved','Month Saved','Icon','success')        
            
            %Refresh Plots
            BalancesPlot(app)
            IncomeExpensePlot(app)
                    
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            app.Year = app.YearDropDown.Value;               
            app.YearName = strcat('Y',num2str(app.Year));
                        
        end

        % Selection change function: UpperTabs
        function UpperTabsSelectionChanged(app, event)
            switch app.UpperTabs.SelectedTab.Title
                case 'Balance Sheet'
                    app.LowerTabs.SelectedTab = app.LowerBalancesTab;                    
                case 'Income & Expense'
                    app.LowerTabs.SelectedTab = app.LowerIncomeExpenseTab;                                
            end
            
        end

        % Selection change function: LowerTabs
        function LowerTabsSelectionChanged(app, event)
            switch app.LowerTabs.SelectedTab.Title
                case 'Balance Sheet'
                    app.UpperTabs.SelectedTab = app.UpperBalancesTab;                    
                case 'Income & Expense'
                    app.UpperTabs.SelectedTab = app.UpperIncomeExpenseTab;                                
            end

        end

        % Button pushed function: LoadMonthButton
        function LoadMonthButtonPushed(app, event)
            LoadMonth(app)           
            
        end

        % Menu selected function: ImportExpensescsvMenu
        function ImportExpensescsvMenuSelected(app, event)
            app.Data.(app.YearName)(app.Month).Expenses_Lists = ExpensesReader(app);
            
            ExpenseTableRefresh(app)
        end

        % Cell edit callback: ExpenseTable
        function ExpenseTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.Data.(app.YearName)(app.Month).Expenses_Lists(indices(1)).Type = newData;       

        end

        % Menu selected function: ClonePreviousMonthMenu
        function ClonePreviousMonthMenuSelected(app, event)
            %Find year and month of previous month
            if app.Month == 1 
                PrevYear = strcat('Y',num2str(app.Year-1)); 
                PrevMonth = 12;
            else
                PrevYear = app.Year;
                PrevMonth = app.Month - 1;
            end
            
            %Find Balances Deltas
            try
                app.Data.(app.YearName)(app.Month).Balances = app.Data.(PrevYear)(PrevMonth).Balances;
                app.Data.(app.YearName)(app.Month).Incomes  = app.Data.(PrevYear)(PrevMonth).Incomes;
            catch
%                 app.Data.(app.YearName)(app.Month).Balance_Deltas.WellsFargo = 0;
%                 app.Data.(app.YearName)(app.Month).Balance_Deltas.RobinHood = 0;
               
            end  
            
            LoadMonth(app)

        end

        % Menu selected function: SetDataDirectoryMenu
        function SetDataDirectoryMenuSelected(app, event)
            %Create pop-up to show current data directory and give user
            %option to set it
            Selection = uiconfirm(app.FinanceTracker,sprintf('Current Data Directory:\n%s', app.DataDirectory),'Data Directory','Options',{'Set','Close'}, ...
           'DefaultOption',1,'CancelOption',2,'Icon','none');

            switch Selection
                case 'Set'
                    try
                        app.DataDirectory = uigetdir(app.DataDirectory,'Set Data Directory');
                    catch
                        app.DataDirectory = uigetdir('C:\','Set Data Directory');
                    end
                    app.FinanceTracker.Name = sprintf('Finance Tracker | %s',app.DataDirectory);
                case 'Close'
                    return
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FinanceTracker = uifigure('Visible', 'off');
            app.FinanceTracker.Name = 'Finance Tracker';
            app.FinanceTracker.Icon = fullfile(pathToMLAPP, 'Images', 'Icon.png');
            app.FileMenu = uimenu(app.FinanceTracker);
            app.FileMenu.Text = ' File ';
            app.SetDataDirectoryMenu = uimenu(app.FileMenu);
            app.SetDataDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetDataDirectoryMenuSelected, true);
            app.SetDataDirectoryMenu.Text = 'Set Data Directory';
            app.ImportExpensescsvMenu = uimenu(app.FileMenu);
            app.ImportExpensescsvMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportExpensescsvMenuSelected, true);
            app.ImportExpensescsvMenu.Separator = 'on';
            app.ImportExpensescsvMenu.Text = 'Import Expenses (.csv)';
            app.ClonePreviousMonthMenu = uimenu(app.FileMenu);
            app.ClonePreviousMonthMenu.MenuSelectedFcn = createCallbackFcn(app, @ClonePreviousMonthMenuSelected, true);
            app.ClonePreviousMonthMenu.Tooltip = {'Clones data from the previous month (balances and icnomes only)'};
            app.ClonePreviousMonthMenu.Separator = 'on';
            app.ClonePreviousMonthMenu.Text = 'Clone Previous Month';
            app.UpperTabs = uitabgroup(app.FinanceTracker);
            app.UpperTabs.SelectionChangedFcn = createCallbackFcn(app, @UpperTabsSelectionChanged, true);
            app.UpperBalancesTab = uitab(app.UpperTabs);
            app.UpperBalancesTab.Title = 'Balance Sheet';
            app.Balance_Axes = uiaxes(app.UpperBalancesTab);
            title(app.Balance_Axes, 'Title')
            xlabel(app.Balance_Axes, 'Month')
            ylabel(app.Balance_Axes, 'Amount ($)')
            app.Balance_Axes.XLim = [1 12];
            app.Balance_Axes.XTickLabel = {'JAN'; 'FEB'; 'MAR'; 'APR'; 'MAY'; 'JUN'; 'JUL'; 'AUG'; 'SEP'; 'OCT'; 'NOV'; 'DEC'};
            app.UpperIncomeExpenseTab = uitab(app.UpperTabs);
            app.UpperIncomeExpenseTab.Title = 'Income & Expense';
            app.IncExp_Axes = uiaxes(app.UpperIncomeExpenseTab);
            title(app.IncExp_Axes, 'Title')
            xlabel(app.IncExp_Axes, 'Month')
            ylabel(app.IncExp_Axes, 'Amount ($)')
            app.IncExp_Axes.XLim = [1 12];
            app.IncExp_Axes.XTickLabel = {'JAN'; 'FEB'; 'MAR'; 'APR'; 'MAY'; 'JUN'; 'JUL'; 'AUG'; 'SEP'; 'OCT'; 'NOV'; 'DEC'};
            app.IncPie_Axes = uiaxes(app.UpperIncomeExpenseTab);
            title(app.IncPie_Axes, 'Title')
            xlabel(app.IncPie_Axes, 'X')
            ylabel(app.IncPie_Axes, 'Y')
            app.ExpPie_Axes = uiaxes(app.UpperIncomeExpenseTab);
            title(app.ExpPie_Axes, 'Title')
            xlabel(app.ExpPie_Axes, 'X')
            ylabel(app.ExpPie_Axes, 'Y')
            app.MonthPanel = uipanel(app.FinanceTracker);
            app.MonthPanel.Title = 'Month';
            app.YearDropDownLabel = uilabel(app.MonthPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.MonthPanel);
            app.YearDropDown.Items = {'2020', '2021', '2022', '2023', '2024', '2025'};
            app.YearDropDown.Editable = 'on';
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2020';
            app.MonthSpinner = uispinner(app.MonthPanel);
            app.MonthSpinner.Limits = [-12 -1];
            app.MonthSpinner.RoundFractionalValues = 'on';
            app.MonthSpinner.ValueChangedFcn = createCallbackFcn(app, @MonthSpinnerValueChanged, true);
            app.MonthSpinner.Value = -1;
            app.NotesTextAreaLabel = uilabel(app.MonthPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.MonthPanel);
            app.SaveMonthButton = uibutton(app.MonthPanel, 'push');
            app.SaveMonthButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMonthButtonPushed, true);
            app.SaveMonthButton.Text = 'Save Month';
            app.MonthDropDownLabel = uilabel(app.MonthPanel);
            app.MonthDropDownLabel.Text = 'Month';
            app.MonthDropDown = uidropdown(app.MonthPanel);
            app.MonthDropDown.Items = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            app.MonthDropDown.Editable = 'on';
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'JAN';
            app.LoadMonthButton = uibutton(app.MonthPanel, 'push');
            app.LoadMonthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMonthButtonPushed, true);
            app.LoadMonthButton.Text = 'Load Month';
            app.LowerTabs = uitabgroup(app.FinanceTracker);
            app.LowerTabs.SelectionChangedFcn = createCallbackFcn(app, @LowerTabsSelectionChanged, true);
            app.LowerBalancesTab = uitab(app.LowerTabs);
            app.LowerBalancesTab.Title = 'Balance Sheet';
            app.WellsFargoLabel = uilabel(app.LowerBalancesTab);
            app.WellsFargoLabel.Text = 'Wells Fargo';
            app.WellsFargoBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.WellsFargoBox.ValueDisplayFormat = '%.2f';
            app.RobinhoodEditFieldLabel = uilabel(app.LowerBalancesTab);
            app.RobinhoodEditFieldLabel.Text = 'Robinhood';
            app.RobinhoodBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.RobinhoodBox.ValueDisplayFormat = '%.2f';
            app.PayPalEditFieldLabel = uilabel(app.LowerBalancesTab);
            app.PayPalEditFieldLabel.Text = 'PayPal';
            app.PayPalBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.PayPalBox.ValueDisplayFormat = '%.2f';
            app.NSLSCEditFieldLabel = uilabel(app.LowerBalancesTab);
            app.NSLSCEditFieldLabel.Text = 'NSLSC';
            app.NSLSCBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.NSLSCBox.ValueDisplayFormat = '%.2f';
            app.AssetsLabel = uilabel(app.LowerBalancesTab);
            app.AssetsLabel.Text = 'Assets';
            app.AssetsBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.AssetsBox.ValueDisplayFormat = '%.2f';
            app.FinnCULabel = uilabel(app.LowerBalancesTab);
            app.FinnCULabel.Text = 'FinnCU';
            app.FinnCUBox = uieditfield(app.LowerBalancesTab, 'numeric');
            app.FinnCUBox.ValueDisplayFormat = '%.2f';
            app.UITable = uitable(app.LowerBalancesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LowerIncomeExpenseTab = uitab(app.LowerTabs);
            app.LowerIncomeExpenseTab.Title = 'Income & Expense';
            app.IncomeJobLabel = uilabel(app.LowerIncomeExpenseTab);
            app.IncomeJobLabel.Text = 'Income - Job';
            app.IncomeJob = uieditfield(app.LowerIncomeExpenseTab, 'numeric');
            app.IncomeJob.ValueDisplayFormat = '%.2f';
            app.IncomeOtherLabel = uilabel(app.LowerIncomeExpenseTab);
            app.IncomeOtherLabel.Text = 'Income - Other';
            app.IncomeOther = uieditfield(app.LowerIncomeExpenseTab, 'numeric');
            app.IncomeOther.ValueDisplayFormat = '%.2f';
            app.ExpenseTable = uitable(app.LowerIncomeExpenseTab);
            app.ExpenseTable.ColumnName = {'Type'; 'Amount'; 'Description'};
            app.ExpenseTable.RowName = {};
            app.ExpenseTable.CellEditCallback = createCallbackFcn(app, @ExpenseTableCellEdit, true);
            app.ContextMenu = uicontextmenu(app.FinanceTracker);
            app.AddItemMenu = uimenu(app.ContextMenu);
            app.AddItemMenu.Text = 'Add Item';
            app.DeleteItemMenu = uimenu(app.ContextMenu);
            app.DeleteItemMenu.Text = 'Delete Item';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.FinanceTracker.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Finance_Tracker_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinanceTracker)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinanceTracker)
        end
    end
end